classdef assignmentApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ParaleterLabel              matlab.ui.control.Label
        Stop_Pushbutton             matlab.ui.control.Button
        NumberofCansEditFieldLabel  matlab.ui.control.Label
        Number_of_Objects           matlab.ui.control.NumericEditField
        Start_Pushbutton            matlab.ui.control.Button
        ColourSelectionLabel        matlab.ui.control.Label
        Colour_Select               matlab.ui.control.DropDown
        CheckBox                    matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        colourValue;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            main;           
        end

        % Button pushed function: Stop_Pushbutton
        function iStop_Pressed(app, event)
            controlFn(0);
        end

        % Value changed function: Number_of_Objects
        function iNumber_Of_Objects(app, event)
            app.Number_of_Objects.Limits = [0 10];
            value = app.Number_of_Objects.Value;
            checkInput(value);
        end

        % Button pushed function: Start_Pushbutton
        function iStart_Pressed(app, event)
            controlFn(1);
        end

        % Value changed function: Colour_Select
        function iColour_Dropdown(app, event)
            value = app.Colour_Select.Value;
            if strcmp(value, 'Red')
                app.colourValue = 1;
            elseif strcmp(value, 'Green')
                app.colourValue = 2;
            elseif strcmp(value, 'Blue')
                app.colourValue = 3;
            else
                app.colourValue = 4;
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value
                selectColour(app.colourValue);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParaleterLabel = uilabel(app.UIFigure);
            app.ParaleterLabel.Text = 'SET SYSTEM PARAMETERS';
            app.Stop_Pushbutton = uibutton(app.UIFigure, 'push');
            app.Stop_Pushbutton.ButtonPushedFcn = createCallbackFcn(app, @iStop_Pressed, true);
            app.Stop_Pushbutton.Tag = 'System_Estop';
            app.Stop_Pushbutton.Text = 'STOP';
            app.NumberofCansEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofCansEditFieldLabel.Text = 'Number of Cans';
            app.Number_of_Objects = uieditfield(app.UIFigure, 'numeric');
            app.Number_of_Objects.ValueChangedFcn = createCallbackFcn(app, @iNumber_Of_Objects, true);
            app.Start_Pushbutton = uibutton(app.UIFigure, 'push');
            app.Start_Pushbutton.ButtonPushedFcn = createCallbackFcn(app, @iStart_Pressed, true);
            app.Start_Pushbutton.Text = 'START';
            app.ColourSelectionLabel = uilabel(app.UIFigure);
            app.ColourSelectionLabel.Text = 'Colour Selection';
            app.Colour_Select = uidropdown(app.UIFigure);
            app.Colour_Select.Items = {'Red', 'Green', 'Blue', 'Default'};
            app.Colour_Select.ValueChangedFcn = createCallbackFcn(app, @iColour_Dropdown, true);
            app.Colour_Select.Value = 'Red';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignmentApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end